classdef unified_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        RecordTab                      matlab.ui.container.Tab
        Record_DButton                 matlab.ui.control.Button
        Record_UButton                 matlab.ui.control.Button
        Record_TIMEDIVKnob             matlab.ui.control.DiscreteKnob
        TIMEDIVKnobLabel               matlab.ui.control.Label
        Record_VOLTSDIVKnob            matlab.ui.control.DiscreteKnob
        VOLTSDIVKnobLabel              matlab.ui.control.Label
        Record_RButton                 matlab.ui.control.Button
        Record_LButton                 matlab.ui.control.Button
        Record_FreqHzEditField         matlab.ui.control.NumericEditField
        FreqHzEditField_2Label         matlab.ui.control.Label
        Record_tmsEditField            matlab.ui.control.NumericEditField
        tmsEditFieldLabel              matlab.ui.control.Label
        Record_AvgmVEditField          matlab.ui.control.NumericEditField
        AvgmVEditFieldLabel            matlab.ui.control.Label
        Record_PPAmpmVEditField        matlab.ui.control.NumericEditField
        PPAmpmVEditFieldLabel          matlab.ui.control.Label
        Record_MinmVEditField          matlab.ui.control.NumericEditField
        MinmVEditFieldLabel            matlab.ui.control.Label
        Record_MaxmVEditField          matlab.ui.control.NumericEditField
        MaxmVEditFieldLabel            matlab.ui.control.Label
        Record_RecordLamp              matlab.ui.control.Lamp
        Record_SAVEButton              matlab.ui.control.Button
        Record_RECORDButton            matlab.ui.control.Button
        Record_AUTOSETButton           matlab.ui.control.Button
        Record_RUNSTOPButton           matlab.ui.control.Button
        Record_Plot                    matlab.ui.control.UIAxes
        PlaybackTab                    matlab.ui.container.Tab
        Playback_running_tmsEditField  matlab.ui.control.NumericEditField
        tmsEditFieldLabel_2            matlab.ui.control.Label
        Playback_LoopSwitch            matlab.ui.control.Switch
        LoopSwitchLabel                matlab.ui.control.Label
        Playback_MinmVEditField        matlab.ui.control.NumericEditField
        MinmVEditFieldLabel_2          matlab.ui.control.Label
        Playback_AvgmVEditField        matlab.ui.control.NumericEditField
        AvgmVEditFieldLabel_2          matlab.ui.control.Label
        Playback_FreqHzEditField       matlab.ui.control.NumericEditField
        FreqHzEditField_2Label_2       matlab.ui.control.Label
        Playback_PPAmpmVEditField      matlab.ui.control.NumericEditField
        PPAmpVEditField_2Label_2       matlab.ui.control.Label
        Playback_MaxmVEditField        matlab.ui.control.NumericEditField
        MaxmVEditFieldLabel_2          matlab.ui.control.Label
        Playback_tmsEditField          matlab.ui.control.NumericEditField
        tmsEditField_2Label            matlab.ui.control.Label
        Playback_SearchButton          matlab.ui.control.Button
        Playback_TIMEDIVKnob           matlab.ui.control.DiscreteKnob
        TIMEDIVKnob_2Label             matlab.ui.control.Label
        Playback_VOLTSDIVKnob          matlab.ui.control.DiscreteKnob
        VOLTSDIVKnob_2Label            matlab.ui.control.Label
        Playback_LButton               matlab.ui.control.Button
        Playback_DButton               matlab.ui.control.Button
        Playback_UButton               matlab.ui.control.Button
        Playback_RButton               matlab.ui.control.Button
        Playback_RUNSTOPButton         matlab.ui.control.Button
        Playback_FileEditField         matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        Playback_Lamp                  matlab.ui.control.Lamp
        Playback_ZeroingButton         matlab.ui.control.Button
        Playback_DelayConstEditField   matlab.ui.control.NumericEditField
        Playback_DelayConstSlider      matlab.ui.control.Slider
        Playback_Plot                  matlab.ui.control.UIAxes
        PortsDropDown                  matlab.ui.control.DropDown
        PortsDropDownLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        device % The Seeeduino device

        default_port_message='Select a port'
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Record
        Record_DispVoltBuffer = [] % Buffer for storing data values
        Record_DispTimestampBuffer = [] % Buffer for storing timestamp values

        saveVoltBuffer = []
        saveTimestampBuffer=[]
        
        
        Record_DataTimer % Timer for data acquisition
        Record_plotTimer % Timer for updating the plot
        showLength = 1000 % Number of points to show on the plot

        % RUN/STOP Btn Click
        isRunning logical = true % Flag to updating the plot

        % RECORD Btn
        isRecording logical = false
        
        % Click management on the plot
        record_plot_clicked logical = false % To track the number of clicks
        vLine1 % To store the handle to the first vertical line
        vLine2 % To store the handle to the second vertical line
        shadedArea % To store the handle to the shaded area patch

        % Zoom/Shift on the plot
        Record_tTickSize
        Record_vTickSize
        
        Record_tOffsetByTick
        Record_vOffsetByTick
        
        Record_tTickNumDisp
        Record_vTickNumDisp

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Playback
        Playback_DispVoltBuffer = [] 
        Playback_DispTimestampBuffer = [] 

        Playback_tTickSize
        Playback_vTickSize
        
        Playback_tOffsetByTick
        Playback_vOffsetByTick
        
        Playback_tTickNumDisp
        Playback_vTickNumDisp
    end
    
    methods (Access = private)
        
        function acquireData(app)
            if ~isempty(app.device)
                while app.device.NumBytesAvailable > 0
                    dataLine = readline(app.device);
                    dataParts = strsplit(dataLine, ',');
                    if length(dataParts) ~= 2
                        error('dataline is wrong, not [timestamps, volts]')
                    end

                    millis = str2double(dataParts{1});
                    numericValue = str2double(dataParts{2});
                    if ~isnan(numericValue) && ~isnan(millis)
                        elapsedMillisec = millis;
                        if app.isRunning
                            app.Record_DispVoltBuffer = [app.Record_DispVoltBuffer, numericValue];
                            app.Record_DispTimestampBuffer = [app.Record_DispTimestampBuffer, elapsedMillisec];
                        end

                        if app.isRecording
                            app.saveVoltBuffer=[app.saveVoltBuffer numericValue];
                            app.saveTimestampBuffer=[app.saveTimestampBuffer elapsedMillisec];
                        end
                        
                        if length(app.Record_DispVoltBuffer) > app.showLength
                            app.Record_DispVoltBuffer = app.Record_DispVoltBuffer(end-app.showLength+1:end);
                            app.Record_DispTimestampBuffer = app.Record_DispTimestampBuffer(end-app.showLength+1:end);
                        end
                    end
                end
            end
        end
        
        function updatePlot(app)
            if(app.isRunning && ~isempty(app.Record_DispTimestampBuffer))
                app.Record_plot();

                % Edit the features
                app.Record_tmsEditField.Value=min(app.Record_DispVoltBuffer);
                app.Record_MaxmVEditField.Value=max(app.Record_DispVoltBuffer);
                app.Record_PPAmpmVEditField.Value=app.Record_MaxmVEditField.Value-app.Record_tmsEditField.Value;
                app.Record_AvgmVEditField.Value=mean(app.Record_DispVoltBuffer);
                app.Record_FreqHzEditField.Value=app.DomFreq(app.Record_DispTimestampBuffer,app.Record_DispVoltBuffer);
            end
        end

        
        function resetMarkOnRecordPlot(app)
            if isgraphics(app.vLine1, 'line')
                delete(app.vLine1);
            end
            if isgraphics(app.vLine2, 'line')
                delete(app.vLine2);
            end
            if isgraphics(app.shadedArea, 'patch')
                delete(app.shadedArea);
            end
        end
        
        function saveCSV(app, timestamps, volts)
            % Open a file save dialog allowing the user to specify a CSV file
            [file, path] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save as');
            if isequal(file, 0) || isequal(path, 0)
                disp('User clicked Cancel.');
            else
                % Construct the full path where the file will be saved
                fullPath = fullfile(path, file);
                disp(['User selected ', fullPath, ' for saving.']);
                
                % Create a table from the timestamps and volts arrays
                T = table(timestamps(:), volts(:), 'VariableNames', {'Timestamps', 'Volts'});
                
                % Write the table to a CSV file
                writetable(T, fullPath);
                disp(['Data saved to ', fullPath]);
            end
        end

        
        function freq = DomFreq(app,timestamps,volts)

            % Ensure timestamps are uniformly spaced
            dt = mean(diff(timestamps)); % Average time interval between samples
            Fs = 1000/dt; % Sampling frequency
            
            % Remove the DC component by subtracting the mean
            volts = volts - mean(volts);
            
            % Compute the Fast Fourier Transform (FFT)
            Y = fft(volts);
            
            % Get the number of points
            n = length(Y);
            Y = Y(1:floor(n/2)+1); % Use only the first half of Y (positive frequencies)
            
            % Compute the frequency values corresponding to Y
            f = (0:floor(n/2)) * Fs / n;
            
            % Find the magnitude of the FFT, which represents the amplitude of each frequency component
            P = abs(Y);
            
            % Find the index of the dominant frequency (excluding the DC component)
            [~, maxIdx] = max(P(2:end)); % Start from index 2 to exclude the DC component (0 Hz)
            maxIdx = maxIdx + 1; % Adjust index because we started from the second element
            
            % Get the dominant frequency based on the index
            freq = f(maxIdx);

        end
        
        function Record_plot(app)
            % Plot the data
                plot(app.Record_Plot, app.Record_DispTimestampBuffer, app.Record_DispVoltBuffer);
                
                % Ensure there's data to work with
                if ~isempty(app.Record_DispTimestampBuffer)
                    % Calculate the right limit of the x-axis based on the latest timestamp
                    lastTimestamp = app.Record_DispTimestampBuffer(end);
                    
                    % Calculate the right limit of the x-axis
                    rtlim = lastTimestamp + app.Record_tOffsetByTick * app.Record_tTickSize;
                    
                    % Calculate the left limit of the x-axis based on the number of ticks to display and tick size
                    ltlim = rtlim - app.Record_tTickNumDisp * app.Record_tTickSize;
                    
                    % Set the x-axis limits
                    xlim(app.Record_Plot, [ltlim, rtlim]);
                end
                
                % Update the plot
                drawnow;
        end

        function Playback_plot(app)
            % Plot the data
                plot(app.Playback_Plot, app.Playback_DispTimestampBuffer, app.Playback_DispVoltBuffer);
                
                % Ensure there's data to work with
                if ~isempty(app.Playback_DispTimestampBuffer)
                    % Calculate the right limit of the x-axis based on the latest timestamp
                    lastTimestamp = app.Playback_DispTimestampBuffer(end);
                    
                    % Calculate the right limit of the x-axis
                    rtlim = lastTimestamp + app.Playback_tOffsetByTick * app.Playback_tTickSize;
                    
                    % Calculate the left limit of the x-axis based on the number of ticks to display and tick size
                    ltlim = rtlim - app.Playback_tTickNumDisp * app.Playback_tTickSize;
                    
                    % Set the x-axis limits
                    xlim(app.Playback_Plot, [ltlim, rtlim]);
                end
                
                % Update the plot
                drawnow;

                app.Playback_MinmVEditField.Value=min(app.Playback_DispVoltBuffer);
                app.Playback_MaxmVEditField.Value=max(app.Playback_DispVoltBuffer);
                app.Playback_PPAmpmVEditField.Value=app.Playback_MaxmVEditField.Value-app.Playback_tmsEditField.Value;
                app.Playback_AvgmVEditField.Value=mean(app.Playback_DispVoltBuffer);
                app.Playback_FreqHzEditField.Value=app.DomFreq(app.Playback_DispTimestampBuffer,app.Playback_DispVoltBuffer);

        end


        function [time, volt] = readCSV(app, csv_path)
            % Define keywords to identify the time and voltage columns
            TIME_INDICATORS = {'time', 'sec', 'stamp'};
            VOLT_INDICATORS = {'volt', 'amp', 'meas'};
            
            % Read the CSV file. This assumes the file has a header row.
            opts = detectImportOptions(csv_path);
            data = readtable(csv_path, opts);
            
            % Initialize output variables
            time = [];
            volt = [];
            
            % Loop over all column names to find matches for time and voltage
            for i = 1:length(opts.VariableNames)
                varName = lower(opts.VariableNames{i});  % Convert to lowercase to ensure case-insensitivity
                % Check if the column name contains any of the time indicators
                if any(contains(varName, TIME_INDICATORS))
                    time = data{:, i};
                % Check if the column name contains any of the voltage indicators
                elseif any(contains(varName, VOLT_INDICATORS))
                    volt = -data{:, i};
                end
            end
            
            % Check if time or volt columns were found
            if isempty(time)
                error('Time column not found in the CSV file.');
            end
            if isempty(volt)
                error('Voltage column not found in the CSV file.');
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            availablePorts = serialportlist("available");
            app.PortsDropDown.Items = [app.default_port_message, availablePorts];
            app.PortsDropDown.Value = app.default_port_message;
        
            % Initialize timers but don't start them yet
            app.Record_DataTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.01, ...
                                  'TimerFcn', @(src, event)acquireData(app));
            app.Record_plotTimer = timer('ExecutionMode', 'fixedRate', 'Period', 2, ...
                                  'TimerFcn', @(src, event)updatePlot(app));

            % Plot tick init (Record)
            app.Record_tTickSize = str2double(app.Record_TIMEDIVKnob.Value);
            app.Record_vTickSize = str2double(app.Record_VOLTSDIVKnob.Value);
            
            app.Record_tOffsetByTick=0;
            app.Record_vOffsetByTick=0;

            app.Record_tTickNumDisp=10;

            % Plot tick init (Playback)
            app.Playback_tTickSize = str2double(app.Playback_TIMEDIVKnob.Value);
            app.Playback_vTickSize = str2double(app.Playback_VOLTSDIVKnob.Value);
            
            app.Playback_tOffsetByTick=0;
            app.Playback_vOffsetByTick=0;

            app.Playback_tTickNumDisp=10;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
            if ~isempty(app.Record_DataTimer)
                stop(app.Record_DataTimer);
                delete(app.Record_DataTimer);
            end
            if ~isempty(app.Record_plotTimer)
                stop(app.Record_plotTimer);
                delete(app.Record_plotTimer);
            end
            if ~isempty(app.device)
                delete(app.device);
            end
            delete(app.UIFigure);

        end

        % Value changed function: PortsDropDown
        function PortsDropDownValueChanged(app, event)

            value = app.PortsDropDown.Value;
            if ~strcmp(value, app.default_port_message)
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                try
                    app.device = serialport(value, 9600);
                    configureTerminator(app.device, "CR/LF");
                    % Start timers when a valid port is selected and device is configured
                    start(app.Record_DataTimer);
                    start(app.Record_plotTimer);
                catch exception
                    uialert(app.UIFigure, exception.message, 'Serial Port Error');
                    app.PortsDropDown.Value = app.default_port_message;
                end
            else
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                % Stop timers if the default message is selected
                stop(app.Record_DataTimer);
                stop(app.Record_plotTimer);
            end

        end

        % Button down function: Record_Plot
        function Record_PlotButtonDown(app, event)
            % Get the current point clicked by the user
            currentPoint = app.Record_Plot.CurrentPoint;
            xClick = currentPoint(1,1); % X-coordinate of the click
            
            if ~app.isRunning % Can mark only when the plot updating pauses
                if ~app.record_plot_clicked % First click
                    app.resetMarkOnRecordPlot();
                    app.vLine1 = line(app.Record_Plot, [xClick xClick], ...
                                      app.Record_Plot.YLim, 'Color', 'r', 'LineWidth', 2);
                    app.record_plot_clicked = true;
                else % Second Click
                    if isgraphics(app.vLine1, 'line')
                        pastX = app.vLine1.XData(1);
                        if xClick > pastX
                            app.vLine2 = line(app.Record_Plot, [xClick xClick], ...
                                              app.Record_Plot.YLim, 'Color', 'b', 'LineWidth', 2);
                            app.shadedArea = patch(app.Record_Plot, ...
                                                   'XData', [pastX, pastX, xClick, xClick], ...
                                                   'YData', [app.Record_Plot.YLim(1), app.Record_Plot.YLim(2), app.Record_Plot.YLim(2), app.Record_Plot.YLim(1)], ...
                                                   'FaceColor', [0.65, 0.65, 0.65], 'FaceAlpha', 0.3);
                            app.shadedArea.ButtonDownFcn = createCallbackFcn(app, @RecordPlotButtonDown, true);
                            app.record_plot_clicked = false; % Reset the click state
                        else
                            app.resetMarkOnRecordPlot();
                            app.vLine1 = line(app.Record_Plot, [xClick xClick], ...
                                          app.Record_Plot.YLim, 'Color', 'r', 'LineWidth', 2);
                        end
                    end
                end
            end
        end

        % Button pushed function: Record_RUNSTOPButton
        function Record_RUNSTOPButtonPushed(app, event)
            if app.isRunning % Stops updating plot
                app.resetMarkOnRecordPlot();
            end
            app.isRunning=~app.isRunning;
        end

        % Button pushed function: Record_SAVEButton
        function Record_SAVEButtonPushed(app, event)

            if isgraphics(app.vLine1, 'line') && isgraphics(app.vLine2, 'line')
                % Extract the X coordinates of the two lines
                x1 = app.vLine1.XData(1)
                x2 = app.vLine2.XData(1)
                app.Record_DispTimestampBuffer
        
                % Ensure x1 is the smaller value
                if x1 > x2
                    [x1, x2] = deal(x2, x1); % Swap x1 and x2
                end
        
                % Find the indices of the data points that fall within the range [x1, x2]
                inRangeIndices = find(app.Record_DispTimestampBuffer >= x1 & app.Record_DispTimestampBuffer <= x2);
        
                if ~isempty(inRangeIndices)
                    % Extract the data points that fall within the range
                    filteredTimestamps = app.Record_DispTimestampBuffer(inRangeIndices);
                    filteredVolts = app.Record_DispVoltBuffer(inRangeIndices);
        
                    % Call saveCSV to save the filtered data
                    app.saveCSV(filteredTimestamps, filteredVolts);
                else
                    % Handle the case where no data falls within the selected range
                    uialert(app.UIFigure, 'No data falls within the selected range.', 'Save Error');
                end
            else
                % Handle the case where one or both lines have not been placed
                uialert(app.UIFigure, 'Please select a range by placing both lines on the plot.', 'Save Error');
            end

        end

        % Button pushed function: Record_RECORDButton
        function Record_RECORDButtonPushed(app, event)
            if app.isRecording % Stop recording and save the data
                app.saveCSV(app.saveTimestampBuffer, app.saveVoltBuffer);
                app.Record_RecordLamp.Color=[0.80,0.80,0.80];
            else % Start recording
                app.Record_RecordLamp.Color='red';
      
            end
            app.isRecording=~app.isRecording;
        end

        % Value changed function: Record_VOLTSDIVKnob
        function Record_VOLTSDIVKnobValueChanged(app, event)
            app.Record_vTickSize = str2double(app.Record_VOLTSDIVKnob.Value);
            app.Record_plot();
        end

        % Value changed function: Record_TIMEDIVKnob
        function Record_TIMEDIVKnobValueChanged(app, event)
            app.Record_tTickSize = str2double(app.Record_TIMEDIVKnob.Value);
            app.Record_plot();
        end

        % Button pushed function: Record_LButton
        function Record_LButtonPushed(app, event)
            app.Record_tOffsetByTick=app.Record_tOffsetByTick-1;
            app.Record_plot();
        end

        % Button pushed function: Record_RButton
        function Record_RButtonPushed(app, event)
            app.Record_tOffsetByTick=app.Record_tOffsetByTick+1;
            app.Record_plot();
        end

        % Button pushed function: Record_UButton
        function Record_UButtonPushed(app, event)
            app.Record_vOffsetByTick=app.Record_vOffsetByTick+1;
            app.Record_plot();
        end

        % Button pushed function: Record_DButton
        function Record_DButtonPushed(app, event)
            app.Record_vOffsetByTick=app.Record_vOffsetByTick-1;
            app.Record_plot();
        end

        % Button pushed function: Playback_SearchButton
        function Playback_SearchButtonPushed(app, event)
            % Open a file save dialog allowing the user to specify a CSV file
            [file, path] = uigetfile({'*.csv','CSV Files (*.csv)'}, 'Select the file.');
            if isequal(file, 0) || isequal(path, 0)
                disp('User clicked Cancel.');
            else
                % Construct the full path where the file will be saved
                fullPath = fullfile(path, file);
                disp(['User selected ', fullPath, ' for playing.']);
                
                % Create a table from the timestamps and volts arrays
                [app.Playback_DispTimestampBuffer, app.Playback_DispVoltBuffer]=app.readCSV(fullPath);
                app.Playback_Plot();

                app.Playback_FileEditField.Value=file;
            end
        end

        % Button pushed function: Playback_LButton
        function Playback_LButtonPushed(app, event)
            app.Playback_tOffsetByTick=app.Playback_tOffsetByTick-1;
            app.Playback_plot();
        end

        % Button pushed function: Playback_DButton
        function Playback_DButtonPushed(app, event)
            app.Playback_vOffsetByTick=app.Playback_vOffsetByTick-1;
            app.Playback_plot();
        end

        % Button pushed function: Playback_UButton
        function Playback_UButtonPushed(app, event)
            app.Playback_vOffsetByTick=app.Playback_vOffsetByTick+1;
            app.Playback_plot();
        end

        % Button pushed function: Playback_RButton
        function Playback_RButtonPushed(app, event)
            app.Playback_tOffsetByTick=app.Playback_tOffsetByTick+1;
            app.Playback_plot();
        end

        % Value changed function: Playback_TIMEDIVKnob
        function Playback_TIMEDIVKnobValueChanged(app, event)
            app.Playback_tTickSize = str2double(app.Playback_TIMEDIVKnob.Value);
            app.Playback_plot();
        end

        % Value changed function: Playback_VOLTSDIVKnob
        function Playback_VOLTSDIVKnobValueChanged(app, event)
            app.Playback_vTickSize = str2double(app.Playback_VOLTSDIVKnob.Value);
            app.Playback_plot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PortsDropDownLabel = uilabel(app.UIFigure);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.UIFigure);
            app.PortsDropDown.ValueChangedFcn = createCallbackFcn(app, @PortsDropDownValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.Record_Plot = uiaxes(app.RecordTab);
            zlabel(app.Record_Plot, 'Z')
            app.Record_Plot.XTickLabel = '';
            app.Record_Plot.YTickLabel = '';
            app.Record_Plot.ButtonDownFcn = createCallbackFcn(app, @Record_PlotButtonDown, true);
            app.Record_RUNSTOPButton = uibutton(app.RecordTab, 'push');
            app.Record_RUNSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @Record_RUNSTOPButtonPushed, true);
            app.Record_RUNSTOPButton.Text = 'RUN/STOP';
            app.Record_AUTOSETButton = uibutton(app.RecordTab, 'push');
            app.Record_AUTOSETButton.Text = 'AUTOSET';
            app.Record_RECORDButton = uibutton(app.RecordTab, 'push');
            app.Record_RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @Record_RECORDButtonPushed, true);
            app.Record_RECORDButton.Text = 'RECORD';
            app.Record_SAVEButton = uibutton(app.RecordTab, 'push');
            app.Record_SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @Record_SAVEButtonPushed, true);
            app.Record_SAVEButton.Text = 'SAVE';
            app.Record_RecordLamp = uilamp(app.RecordTab);
            app.MaxmVEditFieldLabel = uilabel(app.RecordTab);
            app.MaxmVEditFieldLabel.Text = 'Max [mV]';
            app.Record_MaxmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_MaxmVEditField.Editable = 'off';
            app.MinmVEditFieldLabel = uilabel(app.RecordTab);
            app.MinmVEditFieldLabel.Text = 'Min [mV]';
            app.Record_MinmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_MinmVEditField.Editable = 'off';
            app.PPAmpmVEditFieldLabel = uilabel(app.RecordTab);
            app.PPAmpmVEditFieldLabel.Text = 'P-P Amp [mV]';
            app.Record_PPAmpmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_PPAmpmVEditField.Editable = 'off';
            app.AvgmVEditFieldLabel = uilabel(app.RecordTab);
            app.AvgmVEditFieldLabel.Text = 'Avg [mV]';
            app.Record_AvgmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_AvgmVEditField.Editable = 'off';
            app.tmsEditFieldLabel = uilabel(app.RecordTab);
            app.tmsEditFieldLabel.Text = 'Δt [ms]';
            app.Record_tmsEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_tmsEditField.Editable = 'off';
            app.FreqHzEditField_2Label = uilabel(app.RecordTab);
            app.FreqHzEditField_2Label.Text = 'Freq [Hz]';
            app.Record_FreqHzEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_FreqHzEditField.Editable = 'off';
            app.Record_LButton = uibutton(app.RecordTab, 'push');
            app.Record_LButton.ButtonPushedFcn = createCallbackFcn(app, @Record_LButtonPushed, true);
            app.Record_LButton.Text = 'L';
            app.Record_RButton = uibutton(app.RecordTab, 'push');
            app.Record_RButton.ButtonPushedFcn = createCallbackFcn(app, @Record_RButtonPushed, true);
            app.Record_RButton.Text = 'R';
            app.VOLTSDIVKnobLabel = uilabel(app.RecordTab);
            app.VOLTSDIVKnobLabel.Text = 'VOLTS/DIV';
            app.Record_VOLTSDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.Record_VOLTSDIVKnob.Items = {'10mV', '50mV', '100mV', '200mV', '500mV', '1V', '2V'};
            app.Record_VOLTSDIVKnob.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.Record_VOLTSDIVKnob.ValueChangedFcn = createCallbackFcn(app, @Record_VOLTSDIVKnobValueChanged, true);
            app.Record_VOLTSDIVKnob.Value = '200';
            app.TIMEDIVKnobLabel = uilabel(app.RecordTab);
            app.TIMEDIVKnobLabel.Text = 'TIME/DIV';
            app.Record_TIMEDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.Record_TIMEDIVKnob.Items = {'1ms', '5ms', '10ms', '50ms', '100ms', '200ms', '500ms'};
            app.Record_TIMEDIVKnob.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.Record_TIMEDIVKnob.ValueChangedFcn = createCallbackFcn(app, @Record_TIMEDIVKnobValueChanged, true);
            app.Record_TIMEDIVKnob.Value = '10';
            app.Record_UButton = uibutton(app.RecordTab, 'push');
            app.Record_UButton.ButtonPushedFcn = createCallbackFcn(app, @Record_UButtonPushed, true);
            app.Record_UButton.Text = 'U';
            app.Record_DButton = uibutton(app.RecordTab, 'push');
            app.Record_DButton.ButtonPushedFcn = createCallbackFcn(app, @Record_DButtonPushed, true);
            app.Record_DButton.Text = 'D';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.Playback_Plot = uiaxes(app.PlaybackTab);
            zlabel(app.Playback_Plot, 'Z')
            app.Playback_Plot.XTickLabel = '';
            app.Playback_Plot.YTickLabel = '';
            app.Playback_DelayConstSlider = uislider(app.PlaybackTab);
            app.Playback_DelayConstSlider.Limits = [0 1];
            app.Playback_DelayConstSlider.Value = 0.88;
            app.Playback_DelayConstEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.Playback_ZeroingButton = uibutton(app.PlaybackTab, 'push');
            app.Playback_ZeroingButton.Text = 'Zeroing';
            app.Playback_Lamp = uilamp(app.PlaybackTab);
            app.FileEditFieldLabel = uilabel(app.PlaybackTab);
            app.FileEditFieldLabel.Text = 'File';
            app.Playback_FileEditField = uieditfield(app.PlaybackTab, 'text');
            app.Playback_FileEditField.Editable = 'off';
            app.Playback_RUNSTOPButton = uibutton(app.PlaybackTab, 'push');
            app.Playback_RUNSTOPButton.Text = 'RUN/STOP';
            app.Playback_RButton = uibutton(app.PlaybackTab, 'push');
            app.Playback_RButton.ButtonPushedFcn = createCallbackFcn(app, @Playback_RButtonPushed, true);
            app.Playback_RButton.Text = 'R';
            app.Playback_UButton = uibutton(app.PlaybackTab, 'push');
            app.Playback_UButton.ButtonPushedFcn = createCallbackFcn(app, @Playback_UButtonPushed, true);
            app.Playback_UButton.Text = 'U';
            app.Playback_DButton = uibutton(app.PlaybackTab, 'push');
            app.Playback_DButton.ButtonPushedFcn = createCallbackFcn(app, @Playback_DButtonPushed, true);
            app.Playback_DButton.Text = 'D';
            app.Playback_LButton = uibutton(app.PlaybackTab, 'push');
            app.Playback_LButton.ButtonPushedFcn = createCallbackFcn(app, @Playback_LButtonPushed, true);
            app.Playback_LButton.Text = 'L';
            app.VOLTSDIVKnob_2Label = uilabel(app.PlaybackTab);
            app.VOLTSDIVKnob_2Label.Text = 'VOLTS/DIV';
            app.Playback_VOLTSDIVKnob = uiknob(app.PlaybackTab, 'discrete');
            app.Playback_VOLTSDIVKnob.Items = {'10mV', '50mV', '100mV', '200mV', '500mV', '1V', '2V'};
            app.Playback_VOLTSDIVKnob.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.Playback_VOLTSDIVKnob.ValueChangedFcn = createCallbackFcn(app, @Playback_VOLTSDIVKnobValueChanged, true);
            app.Playback_VOLTSDIVKnob.Value = '10';
            app.TIMEDIVKnob_2Label = uilabel(app.PlaybackTab);
            app.TIMEDIVKnob_2Label.Text = 'TIME/DIV';
            app.Playback_TIMEDIVKnob = uiknob(app.PlaybackTab, 'discrete');
            app.Playback_TIMEDIVKnob.Items = {'1ms', '5ms', '10ms', '50ms', '100ms', '200ms', '500ms'};
            app.Playback_TIMEDIVKnob.ItemsData = {'1', '5', '10', '50', '100', '200', '500'};
            app.Playback_TIMEDIVKnob.ValueChangedFcn = createCallbackFcn(app, @Playback_TIMEDIVKnobValueChanged, true);
            app.Playback_TIMEDIVKnob.Value = '10';
            app.Playback_SearchButton = uibutton(app.PlaybackTab, 'push');
            app.Playback_SearchButton.ButtonPushedFcn = createCallbackFcn(app, @Playback_SearchButtonPushed, true);
            app.Playback_SearchButton.Text = 'Search';
            app.tmsEditField_2Label = uilabel(app.PlaybackTab);
            app.tmsEditField_2Label.Text = 'Δt [ms]';
            app.Playback_tmsEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.Playback_tmsEditField.Editable = 'off';
            app.MaxmVEditFieldLabel_2 = uilabel(app.PlaybackTab);
            app.MaxmVEditFieldLabel_2.Text = 'Max [mV]';
            app.Playback_MaxmVEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.Playback_MaxmVEditField.Editable = 'off';
            app.PPAmpVEditField_2Label_2 = uilabel(app.PlaybackTab);
            app.PPAmpVEditField_2Label_2.Text = 'P-P Amp [mV]';
            app.Playback_PPAmpmVEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.Playback_PPAmpmVEditField.Editable = 'off';
            app.FreqHzEditField_2Label_2 = uilabel(app.PlaybackTab);
            app.FreqHzEditField_2Label_2.Text = 'Freq [Hz]';
            app.Playback_FreqHzEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.Playback_FreqHzEditField.Editable = 'off';
            app.AvgmVEditFieldLabel_2 = uilabel(app.PlaybackTab);
            app.AvgmVEditFieldLabel_2.Text = 'Avg [mV]';
            app.Playback_AvgmVEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.Playback_AvgmVEditField.Editable = 'off';
            app.MinmVEditFieldLabel_2 = uilabel(app.PlaybackTab);
            app.MinmVEditFieldLabel_2.Text = 'Min [mV]';
            app.Playback_MinmVEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.Playback_MinmVEditField.Editable = 'off';
            app.LoopSwitchLabel = uilabel(app.PlaybackTab);
            app.LoopSwitchLabel.Text = 'Loop';
            app.Playback_LoopSwitch = uiswitch(app.PlaybackTab, 'slider');
            app.tmsEditFieldLabel_2 = uilabel(app.PlaybackTab);
            app.tmsEditFieldLabel_2.Text = 't [ms]';
            app.Playback_running_tmsEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.Playback_running_tmsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unified_win

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end